classdef JCS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        StartButton                    matlab.ui.control.Button
        TrialNameEditFieldLabel        matlab.ui.control.Label
        TrialNameEditField             matlab.ui.control.EditField
        StaticTrialNameEditFieldLabel  matlab.ui.control.Label
        StaticTrialNameEditField       matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        PlaneDropDownLabel             matlab.ui.control.Label
        PlaneDropDown                  matlab.ui.control.DropDown
        GCDropDownLabel                matlab.ui.control.Label
        GCDropDown                     matlab.ui.control.DropDown
        UseDLMC5CheckBox               matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        dynamic_trial;
        static_trial;
        AP_f;
        AP_a;
        AP_r;
        BA_f;
        BA_a;
        BA_r;
        BS_f;
        BS_a;
        BS_r;
        new_time;
        R5M_z;
        frames;
        check_gc = 0;
        no_good_cycles;
       plane_value;
       gait_cycle_count;
    end
    
    methods (Access = private)
        
        function results = plot_cycles(app)
        
            plot(app.UIAxes,app.new_time,app.BA_f,'Color','#de2a2a');
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.new_time,app.R5M_z,'Color','#a6a8ab');
            hold(app.UIAxes,'off')
            
            plot(app.UIAxes2,app.new_time,app.BS_f,'Color','#de2a2a');
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,app.new_time,app.R5M_z,'Color','#a6a8ab');
            hold(app.UIAxes2,'off')
            
            
            plot(app.UIAxes3,app.new_time,app.AP_f,'Color','#de2a2a');
            hold(app.UIAxes3,'on')
            plot(app.UIAxes3,app.new_time,app.R5M_z,'Color','#a6a8ab');
            hold(app.UIAxes3,'off')
            
            if(app.no_good_cycles)
                f = errordlg('There are no good gait cycles in this trial. An angular data spreadsheet will not be produced.','No good gait cycles');
            end
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
       %try
       [app.BA_f,app.BA_r,app.BA_a,app.AP_f,app.AP_r,app.AP_a,app.BS_f,app.BS_r,app.BS_a,app.new_time,app.R5M_z,app.frames,app.no_good_cycles,app.gait_cycle_count] = JointCoordinates(app.dynamic_trial,app.static_trial);
       %catch exception
         %if(exception.identifier == "MATLAB:load:couldNotReadFile" || exception.identifier  == "MATLAB:xlsread:FileNotFound")
            %f = errordlg('Error in opening the specified dynamic or static trial. File may not exist or is not within the current directory','Trial not found');          
         %else
            %f = errordlg(["error: " exception.identifier "" exception.message],'Exception');
            
         %end
      
       %end
       
       app.frames
       plot_cycles(app);
        end

        % Value changed function: TrialNameEditField
        function TrialNameEditFieldValueChanged(app, event)
           app.dynamic_trial = app.TrialNameEditField.Value;        
        end

        % Value changed function: StaticTrialNameEditField
        function StaticTrialNameEditFieldValueChanged(app, event)
           app.static_trial = "Trials/" + app.StaticTrialNameEditField.Value + ".xls";    
        end

        % Callback function
        function RugerTrialFixCheckBoxValueChanged(app, event)
          
        
        end

        % Value changed function: PlaneDropDown
        function PlaneDropDownValueChanged(app, event)
            app.plane_value = app.PlaneDropDown.Value;
            
            if(app.check_gc ~= '-' && (str2double(app.check_gc) > app.gait_cycle_count))
                return
            end
            
            if(app.plane_value == "Int/Ext")
                title(app.UIAxes, 'Elbow Int/Extrn')
                title(app.UIAxes2, 'Shoulder Int/Extrn')
                title(app.UIAxes3, 'Carpus Int/Extrn')
                
                if(app.check_gc == '1')          
                    plot(app.UIAxes,app.new_time(1):app.frames(2),app.BA_r(1:app.frames(2)),'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.new_time(1):app.frames(2),app.R5M_z(1:app.frames(2)),'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,app.new_time(1):app.frames(2),app.BS_r(1:app.frames(2)),'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,app.new_time(1):app.frames(2),app.R5M_z(1:app.frames(2)),'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                                      
                    plot(app.UIAxes3,app.new_time(1):app.frames(2),app.AP_r(1:app.frames(2)),'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.new_time(1):app.frames(2),app.R5M_z(1:app.frames(2)),'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                elseif(app.check_gc == '2')
                    plot(app.UIAxes,app.frames(2):app.frames(3),app.BA_r(app.frames(2):app.frames(3)),'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.frames(2):app.frames(3),app.R5M_z(app.frames(2):app.frames(3)),'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,app.frames(2):app.frames(3),app.BS_r(app.frames(2):app.frames(3)),'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,app.frames(2):app.frames(3),app.R5M_z(app.frames(2):app.frames(3)),'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                    
                                      
                    plot(app.UIAxes3,app.frames(2):app.frames(3),app.AP_r(app.frames(2):app.frames(3)),'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.frames(2):app.frames(3),app.R5M_z(app.frames(2):app.frames(3)),'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                elseif(app.check_gc == '3')
                    plot(app.UIAxes,app.frames(3):app.frames(4),app.BA_r(app.frames(3):app.frames(4)),'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.frames(3):app.frames(4),app.R5M_z(app.frames(3):app.frames(4)),'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,app.frames(3):app.frames(4),app.BS_r(app.frames(3):app.frames(4)),'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,app.frames(3):app.frames(4),app.R5M_z(app.frames(3):app.frames(4)),'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                                      
                    plot(app.UIAxes3,app.frames(3):app.frames(4),app.AP_r(app.frames(3):app.frames(4)),'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.frames(3):app.frames(4),app.R5M_z(app.frames(3):app.frames(4)),'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                elseif(app.check_gc == '4')
                    plot(app.UIAxes,app.frames(4):app.frames(5),app.BA_r(app.frames(4):app.frames(5)),'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.frames(4):app.frames(5),app.R5M_z(app.frames(4):app.frames(5)),'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,app.frames(4):app.frames(5),app.BS_r(app.frames(4):app.frames(5)),'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,app.frames(4):app.frames(5),app.R5M_z(app.frames(4):app.frames(5)),'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                                      
                    plot(app.UIAxes3,app.frames(4):app.frames(5),app.AP_r(app.frames(4):app.frames(5)),'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.frames(4):app.frames(5),app.R5M_z(app.frames(4):app.frames(5)),'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                 else %default "-" case
                    plot(app.UIAxes,1:length(app.BA_r),app.BA_r,'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,1:length(app.R5M_z),app.R5M_z,'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,1:length(app.BS_r),app.BS_r,'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,1:length(app.R5M_z),app.R5M_z,'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                    
                    
                    plot(app.UIAxes3,1:length(app.AP_r),app.AP_r,'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,1:length(app.R5M_z),app.R5M_z,'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                end
            end
            
            if(app.plane_value == "F/E")
                title(app.UIAxes, 'Elbow Flex/Extension')
                title(app.UIAxes2, 'Shoulder Flex/Extension')
                title(app.UIAxes3, 'Carpus Flex/Extension')
                
                if(app.check_gc == '1')          
                    plot(app.UIAxes,app.new_time(1):app.frames(2),app.BA_f(1:app.frames(2)),'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.new_time(1):app.frames(2),app.R5M_z(1:app.frames(2)),'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,app.new_time(1):app.frames(2),app.BS_f(1:app.frames(2)),'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,app.new_time(1):app.frames(2),app.R5M_z(1:app.frames(2)),'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                                      
                    plot(app.UIAxes3,app.new_time(1):app.frames(2),app.AP_f(1:app.frames(2)),'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.new_time(1):app.frames(2),app.R5M_z(1:app.frames(2)),'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                elseif(app.check_gc == '2')
                    plot(app.UIAxes,app.frames(2):app.frames(3),app.BA_f(app.frames(2):app.frames(3)),'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.frames(2):app.frames(3),app.R5M_z(app.frames(2):app.frames(3)),'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,app.frames(2):app.frames(3),app.BS_f(app.frames(2):app.frames(3)),'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,app.frames(2):app.frames(3),app.R5M_z(app.frames(2):app.frames(3)),'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                                      
                    plot(app.UIAxes3,app.frames(2):app.frames(3),app.AP_f(app.frames(2):app.frames(3)),'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.frames(2):app.frames(3),app.R5M_z(app.frames(2):app.frames(3)),'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                elseif(app.check_gc == '3')
                    plot(app.UIAxes,app.frames(3):app.frames(4),app.BA_f(app.frames(3):app.frames(4)),'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.frames(3):app.frames(4),app.R5M_z(app.frames(3):app.frames(4)),'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,app.frames(3):app.frames(4),app.BS_f(app.frames(3):app.frames(4)),'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,app.frames(3):app.frames(4),app.R5M_z(app.frames(3):app.frames(4)),'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                                      
                    plot(app.UIAxes3,app.frames(3):app.frames(4),app.AP_f(app.frames(3):app.frames(4)),'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.frames(3):app.frames(4),app.R5M_z(app.frames(3):app.frames(4)),'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                elseif(app.check_gc == '4')
                    plot(app.UIAxes,app.frames(4):app.frames(5),app.BA_f(app.frames(4):app.frames(5)),'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.frames(4):app.frames(5),app.R5M_z(app.frames(4):app.frames(5)),'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,app.frames(4):app.frames(5),app.BS_f(app.frames(4):app.frames(5)),'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,app.frames(4):app.frames(5),app.R5M_z(app.frames(4):app.frames(5)),'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                                      
                    plot(app.UIAxes3,app.frames(4):app.frames(5),app.AP_f(app.frames(4):app.frames(5)),'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.frames(4):app.frames(5),app.R5M_z(app.frames(4):app.frames(5)),'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                 else %default "-" case
                    plot(app.UIAxes,1:length(app.BA_r),app.BA_f,'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,1:length(app.R5M_z),app.R5M_z,'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,1:length(app.BS_r),app.BS_f,'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,1:length(app.R5M_z),app.R5M_z,'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                    
                    
                    plot(app.UIAxes3,1:length(app.AP_r),app.AP_f,'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,1:length(app.R5M_z),app.R5M_z,'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                end
            end
            
            if(app.plane_value == "Add/Abd")

                title(app.UIAxes, 'Elbow Add/Abduction')
                title(app.UIAxes2, 'Shoulder Add/Abduction')
                title(app.UIAxes3, 'Carpus Add/Abduction')
                
                if(app.check_gc == '1')          
                    plot(app.UIAxes,app.new_time(1):app.frames(2),app.BA_a(1:app.frames(2)),'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.new_time(1):app.frames(2),app.R5M_z(1:app.frames(2)),'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,app.new_time(1):app.frames(2),app.BS_a(1:app.frames(2)),'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,app.new_time(1):app.frames(2),app.R5M_z(1:app.frames(2)),'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                                      
                    plot(app.UIAxes3,app.new_time(1):app.frames(2),app.AP_a(1:app.frames(2)),'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.new_time(1):app.frames(2),app.R5M_z(1:app.frames(2)),'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                elseif(app.check_gc == '2')
                    plot(app.UIAxes,app.frames(2):app.frames(3),app.BA_a(app.frames(2):app.frames(3)),'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.frames(2):app.frames(3),app.R5M_z(app.frames(2):app.frames(3)),'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,app.frames(2):app.frames(3),app.BS_a(app.frames(2):app.frames(3)),'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,app.frames(2):app.frames(3),app.R5M_z(app.frames(2):app.frames(3)),'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                                      
                    plot(app.UIAxes3,app.frames(2):app.frames(3),app.AP_a(app.frames(2):app.frames(3)),'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.frames(2):app.frames(3),app.R5M_z(app.frames(2):app.frames(3)),'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                elseif(app.check_gc == '3')
                    plot(app.UIAxes,app.frames(3):app.frames(4),app.BA_a(app.frames(3):app.frames(4)),'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.frames(3):app.frames(4),app.R5M_z(app.frames(3):app.frames(4)),'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,app.frames(3):app.frames(4),app.BS_a(app.frames(3):app.frames(4)),'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,app.frames(3):app.frames(4),app.R5M_z(app.frames(3):app.frames(4)),'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                                      
                    plot(app.UIAxes3,app.frames(3):app.frames(4),app.AP_a(app.frames(3):app.frames(4)),'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.frames(3):app.frames(4),app.R5M_z(app.frames(3):app.frames(4)),'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                elseif(app.check_gc == '4')
                    plot(app.UIAxes,app.frames(4):app.frames(5),app.BA_a(app.frames(4):app.frames(5)),'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,app.frames(4):app.frames(5),app.R5M_z(app.frames(4):app.frames(5)),'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,app.frames(4):app.frames(5),app.BS_a(app.frames(4):app.frames(5)),'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,app.frames(4):app.frames(5),app.R5M_z(app.frames(4):app.frames(5)),'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                                      
                    plot(app.UIAxes3,app.frames(4):app.frames(5),app.AP_a(app.frames(4):app.frames(5)),'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,app.frames(4):app.frames(5),app.R5M_z(app.frames(4):app.frames(5)),'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                 else %default "-" case
                    plot(app.UIAxes,1:length(app.BA_r),app.BA_a,'Color','#de2a2a');
                    hold(app.UIAxes,'on')
                    plot(app.UIAxes,1:length(app.R5M_z),app.R5M_z,'Color','#a6a8ab');
                    hold(app.UIAxes,'off')
                    
                    plot(app.UIAxes2,1:length(app.BS_r),app.BS_a,'Color','#de2a2a');
                    hold(app.UIAxes2,'on')
                    plot(app.UIAxes2,1:length(app.R5M_z),app.R5M_z,'Color','#a6a8ab');
                    hold(app.UIAxes2,'off')
                    
                    
                    plot(app.UIAxes3,1:length(app.AP_r),app.AP_a,'Color','#de2a2a');
                    hold(app.UIAxes3,'on')
                    plot(app.UIAxes3,1:length(app.R5M_z),app.R5M_z,'Color','#a6a8ab');
                    hold(app.UIAxes3,'off')
                end
            end


        end

        % Value changed function: GCDropDown
        function GCDropDownValueChanged(app, event)
            app.check_gc = app.GCDropDown.Value;
            PlaneDropDownValueChanged(app,event)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.TrialNameEditFieldLabel = uilabel(app.UIFigure);
            app.TrialNameEditFieldLabel.Text = 'Trial Name';
            app.TrialNameEditField = uieditfield(app.UIFigure, 'text');
            app.TrialNameEditField.ValueChangedFcn = createCallbackFcn(app, @TrialNameEditFieldValueChanged, true);
            app.TrialNameEditField.Value = 'Colt No Vest 0';
            app.StaticTrialNameEditFieldLabel = uilabel(app.UIFigure);
            app.StaticTrialNameEditFieldLabel.Text = 'Static Trial Name';
            app.StaticTrialNameEditField = uieditfield(app.UIFigure, 'text');
            app.StaticTrialNameEditField.ValueChangedFcn = createCallbackFcn(app, @StaticTrialNameEditFieldValueChanged, true);
            app.StaticTrialNameEditField.Value = 'Colt Cal 0';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Elbow Flex/Extension')
            xlabel(app.UIAxes, 'frame (#)')
            ylabel(app.UIAxes, 'angle (deg)')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Shoulder Flex/Extension')
            xlabel(app.UIAxes2, 'frame (#)')
            ylabel(app.UIAxes2, 'angle (deg)')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Carpus Flex/Extension')
            xlabel(app.UIAxes3, 'frame (#)')
            ylabel(app.UIAxes3, 'angle (deg)')
            app.PlaneDropDownLabel = uilabel(app.UIFigure);
            app.PlaneDropDownLabel.Text = 'Plane';
            app.PlaneDropDown = uidropdown(app.UIFigure);
            app.PlaneDropDown.Items = {'F/E', 'Int/Ext', 'Add/Abd'};
            app.PlaneDropDown.ValueChangedFcn = createCallbackFcn(app, @PlaneDropDownValueChanged, true);
            app.PlaneDropDown.Value = 'F/E';
            app.GCDropDownLabel = uilabel(app.UIFigure);
            app.GCDropDownLabel.Text = 'GC #';
            app.GCDropDown = uidropdown(app.UIFigure);
            app.GCDropDown.Items = {'-', '1', '2', '3', '4'};
            app.GCDropDown.ValueChangedFcn = createCallbackFcn(app, @GCDropDownValueChanged, true);
            app.GCDropDown.Value = '-';
            app.UseDLMC5CheckBox = uicheckbox(app.UIFigure);
            app.UseDLMC5CheckBox.Text = '    Use DLMC5';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = JCS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end